classdef GBPaQ_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_main                  matlab.ui.Figure
        axes_icon                    matlab.ui.control.UIAxes
        axes_tracemap                matlab.ui.control.UIAxes
        axes_logo                    matlab.ui.control.UIAxes
        text19                       matlab.ui.control.Label
        uipanel1                     matlab.ui.container.Panel
        text3                        matlab.ui.control.Label
        uibuttongroup1               matlab.ui.container.ButtonGroup
        radiobutton_image            matlab.ui.control.RadioButton
        radiobutton_node             matlab.ui.control.RadioButton
        uibuttongroup5               matlab.ui.container.ButtonGroup
        label_houghpeaks             matlab.ui.control.Label
        label_houghthreshold         matlab.ui.control.Label
        label_fillgap                matlab.ui.control.Label
        label_minlength              matlab.ui.control.Label
        edit_houghpeaks              matlab.ui.control.EditField
        edit_houghthreshold          matlab.ui.control.EditField
        edit_fillgap                 matlab.ui.control.EditField
        edit_minlength               matlab.ui.control.EditField
        pushbutton_browse            matlab.ui.control.Button
        text13                       matlab.ui.control.Label
        edit_scaling                 matlab.ui.control.EditField
        pushbutton_preview           matlab.ui.control.Button
        edit_filename                matlab.ui.control.EditField
        pushbutton_flipy             matlab.ui.control.Button
        pushbutton_flipx             matlab.ui.control.Button
        uibuttongroup7               matlab.ui.container.ButtonGroup
        label_stats                  matlab.ui.control.Label
        text_message                 matlab.ui.control.Label
        uibgTabMaps                  matlab.ui.container.ButtonGroup
        checkbox_shownodes           matlab.ui.control.CheckBox
        checkbox_tracemap            matlab.ui.control.CheckBox
        uipanel6                     matlab.ui.container.Panel
        checkbox_tracemaplength      matlab.ui.control.CheckBox
        checkbox_segmentmaplength    matlab.ui.control.CheckBox
        checkbox_segmentmapstrike    matlab.ui.control.CheckBox
        uipanel4                     matlab.ui.container.Panel
        checkbox_intensitymap        matlab.ui.control.CheckBox
        checkbox_densitymap          matlab.ui.control.CheckBox
        checkbox_showcircles         matlab.ui.control.CheckBox
        label_numscancircles         matlab.ui.control.Label
        edit_numscancircles          matlab.ui.control.EditField
        uibgTabButtons               matlab.ui.container.ButtonGroup
        pbMapsTab                    matlab.ui.control.Button
        pbAnglesTab                  matlab.ui.control.Button
        pbLengthsTab                 matlab.ui.control.Button
        pbInterceptsTab              matlab.ui.control.Button
        pushbutton_run               matlab.ui.control.Button
        pushbutton_exit              matlab.ui.control.Button
        text4                        matlab.ui.control.Label
        uibgTabLengths               matlab.ui.container.ButtonGroup
        edit_uc                      matlab.ui.control.EditField
        label_uc                     matlab.ui.control.Label
        edit_lc                      matlab.ui.control.EditField
        popupmenu_histolengthbins    matlab.ui.control.DropDown
        text_histolengthbins         matlab.ui.control.Label
        checkbox_crossplot           matlab.ui.control.CheckBox
        checkbox_mle                 matlab.ui.control.CheckBox
        checkbox_censor              matlab.ui.control.CheckBox
        checkbox_logloglength        matlab.ui.control.CheckBox
        checkbox_histolength         matlab.ui.control.CheckBox
        label_lc                     matlab.ui.control.Label
        uibuttongroup12              matlab.ui.container.ButtonGroup
        checkbox_cracktensor         matlab.ui.control.CheckBox
        uibgTabAngles                matlab.ui.container.ButtonGroup
        checkbox_rosecolour          matlab.ui.control.CheckBox
        checkbox_showrosemean        matlab.ui.control.CheckBox
        checkbox_roselengthweighted  matlab.ui.control.CheckBox
        checkbox_histoangle          matlab.ui.control.CheckBox
        checkbox_rose                matlab.ui.control.CheckBox
        edit_degfromnorth            matlab.ui.control.EditField
        label_degfromnorth           matlab.ui.control.Label
        text_histoanglebins          matlab.ui.control.Label
        popupmenu_histoanglebins     matlab.ui.control.DropDown
        text_rosebins                matlab.ui.control.Label
        popupmenu_rosebins           matlab.ui.control.DropDown
        uibgTabIntercepts            matlab.ui.container.ButtonGroup
        checkbox_seganglemin         matlab.ui.control.CheckBox
        checkbox_seganglemax         matlab.ui.control.CheckBox
        checkbox_segintercept        matlab.ui.control.CheckBox
        checkbox_seginterceptnorm    matlab.ui.control.CheckBox
        text_segscanlineint          matlab.ui.control.Label
        popupmenu_segscanlineint     matlab.ui.control.DropDown
        text_seganglebin             matlab.ui.control.Label
        popupmenu_seganglebin        matlab.ui.control.DropDown
        RosediagramsLabel            matlab.ui.control.Label
        PolarplotsLabel              matlab.ui.control.Label
        checkbox_segintmin           matlab.ui.control.CheckBox
        checkbox_segintmax           matlab.ui.control.CheckBox
        checkbox_segintmean          matlab.ui.control.CheckBox
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GBPaQ_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GBPaQ (see VARARGIN)
            
            % Choose default command line output for GBPaQ
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes GBPaQ wait for user response (see UIRESUME)
            % uiwait(handles.figure_main);
            axes(handles.axes_logo) ;
            imLogo = imread('GBPaQlogo.jpeg') ;
            imshow(imLogo) ;
            
            axes(handles.axes_icon) ;
            imIcon = imread('GBPaQicon.jpeg') ;
            imshow(imIcon) ;
            
            axes(handles.axes_tracemap) ;
            
            set(handles.popupmenu_histolengthbins,'Value',2) ;
            set(handles.popupmenu_histoanglebins,'Value',2) ;
            set(handles.popupmenu_rosebins,'Value',2) ;
            
            handles.GBPaQversion = '0.9' ;
            disp(['GBPaQ version ', handles.GBPaQversion]) ;
            % Update handles structure
            guidata(hObject, handles);
            
            v = ver('MATLAB') ;
            iRelease = regexp(v.Release, 'R.....') ;
            nReleaseYear = str2num(v.Release(iRelease+1:iRelease+4)) ;
            if nReleaseYear < 2015
                h = msgbox('Warning: GBPaQ needs MATLAB release of R2015a, or later.', 'Important Warning!') ;
                uiwait(h) ;
            end ;
            
            %Reposition each panel to same location as panel 1
            set(handles.uibgTabLengths,'position',get(handles.uibgTabMaps,'position'));
            set(handles.uibgTabAngles,'position',get(handles.uibgTabMaps,'position'));
            set(handles.uibgTabIntercepts,'position',get(handles.uibgTabMaps,'position'));
            set(handles.uibgTabMaps, 'Visible', 'off') ;
            set(handles.uibgTabLengths, 'Visible', 'off') ;
            set(handles.uibgTabAngles, 'Visible', 'off') ;
            set(handles.uibgTabIntercepts, 'Visible', 'off') ;
        end

        % Value changed function: checkbox_densitymap
        function checkbox_densitymap_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_densitymap (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_densitymap
            if get(hObject, 'Value') || get(handles.checkbox_intensitymap, 'Value')
                set(handles.checkbox_showcircles, 'Enable', 'on') ;
                set(handles.edit_numscancircles, 'Enable', 'on') ;
                set(handles.label_numscancircles, 'Enable', 'on') ;
            else
                set(handles.checkbox_showcircles, 'Enable', 'off') ;
                set(handles.checkbox_showcircles, 'Value', 0) ;
                set(handles.edit_numscancircles, 'Enable', 'off') ;
                set(handles.edit_numscancircles, 'Value', 0) ;
                set(handles.label_numscancircles, 'Enable', 'off') ;
            end
        end

        % Value changed function: checkbox_histoangle
        function checkbox_histoangle_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_histoangle (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_histoangle
            if get(hObject, 'Value')
                set(handles.text_histoanglebins, 'Enable', 'on') ;
                set(handles.popupmenu_histoanglebins, 'Enable', 'on') ;
            else
                set(handles.text_histoanglebins, 'Enable', 'off') ;
                set(handles.popupmenu_histoanglebins, 'Enable', 'off') ;
            end
        end

        % Value changed function: checkbox_histolength
        function checkbox_histolength_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_histolength (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_histolength
            if get(hObject, 'Value')
                set(handles.text_histolengthbins, 'Enable', 'on') ;
                set(handles.popupmenu_histolengthbins, 'Enable', 'on') ;
            else
                set(handles.text_histolengthbins, 'Enable', 'off') ;
                set(handles.popupmenu_histolengthbins, 'Enable', 'off') ;
            end
        end

        % Value changed function: checkbox_intensitymap
        function checkbox_intensitymap_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_intensitymap (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_intensitymap
            if get(hObject, 'Value') || get(handles.checkbox_densitymap, 'Value')
                set(handles.checkbox_showcircles, 'Enable', 'on') ;
                set(handles.edit_numscancircles, 'Enable', 'on') ;
                set(handles.label_numscancircles, 'Enable', 'on') ;
            else
                set(handles.checkbox_showcircles, 'Enable', 'off') ;
                set(handles.checkbox_showcircles, 'Value', 0) ;
                set(handles.edit_numscancircles, 'Enable', 'off') ;
                set(handles.edit_numscancircles, 'Value', 0) ;
                set(handles.label_numscancircles, 'Enable', 'off') ;
            end
        end

        % Value changed function: checkbox_logloglength
        function checkbox_logloglength_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_logloglength (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_logloglength
            if get(hObject, 'Value')
                set(handles.checkbox_censor, 'Enable', 'on') ;
                set(handles.checkbox_mle, 'Enable', 'on') ;
            else
                set(handles.checkbox_censor, 'Enable', 'off') ;
                set(handles.checkbox_mle, 'Enable', 'off') ;
                set(handles.checkbox_censor, 'Value', 0) ;
                set(handles.checkbox_mle, 'Value', 0) ;
                set(handles.edit_lc, 'Enable', 'off') ;
                set(handles.edit_uc, 'Enable', 'off') ;
                set(handles.label_lc, 'Enable', 'off') ;
                set(handles.label_uc, 'Enable', 'off') ;
            end
        end

        % Value changed function: checkbox_mle
        function checkbox_mle_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_mle (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_mle
            if get(hObject, 'Value')
                set(handles.edit_lc, 'Enable', 'on') ;
                set(handles.edit_uc, 'Enable', 'on') ;
                set(handles.label_lc, 'Enable', 'on') ;
                set(handles.label_uc, 'Enable', 'on') ;
            else
                set(handles.edit_lc, 'Enable', 'off') ;
                set(handles.edit_uc, 'Enable', 'off') ;
                set(handles.label_lc, 'Enable', 'off') ;
                set(handles.label_uc, 'Enable', 'off') ;
            end
        end

        % Value changed function: checkbox_rose
        function checkbox_rose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_rose (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_rose
            if get(hObject, 'Value')
                set(handles.text_rosebins, 'Enable', 'on') ;
                set(handles.popupmenu_rosebins, 'Enable', 'on') ;
                set(handles.edit_degfromnorth, 'Enable', 'on') ;
                set(handles.label_degfromnorth, 'Enable', 'on') ;
                set(handles.checkbox_roselengthweighted, 'Enable', 'on') ;
                set(handles.checkbox_showrosemean, 'Enable', 'on') ;
                set(handles.checkbox_rosecolour, 'Enable', 'on') ;
            else
                set(handles.text_rosebins, 'Enable', 'off') ;
                set(handles.popupmenu_rosebins, 'Enable', 'off') ;
                set(handles.edit_degfromnorth, 'Enable', 'off') ;
                set(handles.label_degfromnorth, 'Enable', 'off') ;
                set(handles.checkbox_roselengthweighted, 'Enable', 'off') ;
                set(handles.checkbox_showrosemean, 'Enable', 'off') ;
                set(handles.checkbox_rosecolour, 'Enable', 'off') ;
                set(handles.checkbox_roselengthweighted, 'Value', false) ;
                set(handles.checkbox_showrosemean, 'Value', false) ;
                set(handles.checkbox_rosecolour, 'Value', false) ;
            end
        end

        % Value changed function: checkbox_tracemap
        function checkbox_tracemap_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_tracemap (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_tracemap
            if get(hObject, 'Value')
                set(handles.checkbox_shownodes, 'Enable', 'on') ;
            else
                set(handles.checkbox_shownodes, 'Enable', 'off') ;
                set(handles.checkbox_shownodes, 'Value', 0) ;
            end
        end

        % Button pushed function: pbAnglesTab
        function pbAnglesTab_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbAnglesTab (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uibgTabMaps, 'Visible', 'off') ;
            set(handles.uibgTabLengths, 'Visible', 'off') ;
            set(handles.uibgTabAngles, 'Visible', 'on') ;
            set(handles.uibgTabIntercepts, 'Visible', 'off') ;            
        end

        % Button pushed function: pbLengthsTab
        function pbLengthsTab_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbLengthsTab (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uibgTabMaps, 'Visible', 'off') ;
            set(handles.uibgTabLengths, 'Visible', 'on') ;
            set(handles.uibgTabAngles, 'Visible', 'off') ;
            set(handles.uibgTabIntercepts, 'Visible', 'off') ;
        end

        % Button pushed function: pbMapsTab
        function pbMapsTab_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbMapsTab (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uibgTabMaps, 'Visible', 'on') ;
            set(handles.uibgTabLengths, 'Visible', 'off') ;
            set(handles.uibgTabAngles, 'Visible', 'off') ;
            set(handles.uibgTabIntercepts, 'Visible', 'off') ;
        end

        % Button pushed function: pushbutton_browse
        function pushbutton_browse_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_browse (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            cla(handles.axes_tracemap) ;
            handles.axes_tracemap.YDir = 'normal' ;
            
            %   from v1.8 - all platforms get access to .svg input files
            sFileTypes = {'*.txt'; '*.svg'; '*.jpeg'; '*.jpg'; '*.tiff'; '*.tif'} ;
            
            [ handles.selfile, handles.selpath ] = uigetfile(sFileTypes, 'Select input file(s) for GBPaQ') ;
            figure(app.figure_main) ; 
            assignin('base','user_file_name',handles.selfile)             %save that variable to the workspace to use it later in the file guiPrint.m
            
            if ~isempty(handles.selfile)
            
                sFileName = char(handles.selfile) ;
            
                if ~isempty(regexp(sFileName, 'colour', 'ONCE'))
                    iHex = regexp(sFileName, '[A-F,0-9]{6}', 'ONCE') ;
                    %   check each file has 'HHHHHH' in the name i.e. a valid hex string for colour
                    if isempty(iHex)
                        hError = errordlg('For a colour file, all filenames must contain a 6 character hexadecimal colour code', 'Input error', 'modal') ;
                        uicontrol(handles.edit_filename) ;
                        flagError = true ;
                        return ;
                    else
                        iRGB = strfind(sFileName, 'colour') ;
                        sRGB = sFileName(iRGB+6:iRGB+11) ;
                        sColour = hexRGB(sRGB) ;
                        handles.cstrColours = cellstr(sColour) ;
                    end ;
            
                else
                    %   MATLAB default blue
                    sColour = '[0, 0, 1]' ;
                    handles.cstrColours = cellstr(sColour) ;
                end ;
            
                handles.iCurrentColour = 1 ;
            
                set(handles.edit_filename, 'String', sFileName) ;
                set(handles.pushbutton_preview, 'Enable', 'on') ;
            
                if contains(upper(sFileName), '.TIF') || ...
                   contains(upper(sFileName), '.TIFF') || ...
                   contains(upper(sFileName), '.JPEG') || ...
                   contains(upper(sFileName), '.JPG')
                    set(handles.radiobutton_image, 'Value', 1) ;
                    set(handles.radiobutton_node, 'Value', 0) ;
                    set(handles.edit_houghpeaks, 'Enable', 'on') ;
                    set(handles.edit_houghthreshold, 'Enable', 'on') ;
                    set(handles.edit_fillgap, 'Enable', 'on') ;
                    set(handles.edit_minlength, 'Enable', 'on') ;
                else
                    set(handles.radiobutton_node, 'Value', 1) ;
                    set(handles.radiobutton_image, 'Value', 0) ;
                    set(handles.edit_houghpeaks, 'Enable', 'off') ;
                    set(handles.edit_houghthreshold, 'Enable', 'off') ;
                    set(handles.edit_fillgap, 'Enable', 'off') ;
                    set(handles.edit_minlength, 'Enable', 'off') ;
                end ;
            
            else
            
                set(handles.edit_filename, 'String', '(no file selected)') ;
                set(handles.pushbutton_preview, 'Enable', 'off') ;
            
            end ;
            
            set(handles.pushbutton_flipx, 'Enable', 'off') ;
            set(handles.pushbutton_flipy, 'Enable', 'off') ;
            set(handles.pushbutton_run, 'Enable', 'off') ;
            set(handles.text_message, 'String', 'Click Preview to view the file contents.') ;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_exit
        function pushbutton_exit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close all ;
            close(gcf) ;
        end

        % Button pushed function: pushbutton_flipx
        function pushbutton_flipx_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_flipx (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if strcmp(handles.axes_tracemap.XDir, 'reverse')
                handles.axes_tracemap.XDir = 'normal' ;
            else
                handles.axes_tracemap.XDir = 'reverse' ;
            end ;
            set(handles.text_message, 'String', 'X-axis flipped.') ;
        end

        % Button pushed function: pushbutton_flipy
        function pushbutton_flipy_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_flipy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if strcmp(handles.axes_tracemap.YDir, 'reverse')
                handles.axes_tracemap.YDir = 'normal' ;
            else
                handles.axes_tracemap.YDir = 'reverse' ;
            end ;
            set(handles.text_message, 'String', 'Y-axis flipped.') ;
        end

        % Button pushed function: pushbutton_preview
        function pushbutton_preview_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_preview (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            cla(handles.axes_tracemap) ;
            
            flagError = false ;
            
            %   image file validation
            if get(handles.radiobutton_image, 'Value')
            
                sValue = get(handles.edit_houghpeaks, 'String') ;
                if isnan(str2double(sValue)) || str2double(sValue) < 100 || str2double(sValue) > 5000
                    hError = errordlg('Hough peaks must be a whole number, 100-5000', 'Input error', 'modal') ;
                    uicontrol(handles.edit_houghpeaks) ;
                    flagError = true ;
                    return ;
                end ;
            
                sValue = get(handles.edit_houghthreshold, 'String') ;
                if isnan(str2double(sValue)) || str2double(sValue) <= 0.0 || str2double(sValue) > 1.0
                    hError = errordlg('Hough threshold must be a number > 0.0, and <= 1.0', 'Input error', 'modal') ;
                    uicontrol(handles.edit_houghthreshold) ;
                    flagError = true ;
                    return ;
                end ;
            
                sValue = get(handles.edit_fillgap, 'String') ;
                if isnan(str2double(sValue)) || str2double(sValue) <= 1.0
                    hError = errordlg('Merge gap must be a number of pixels, > 1 and < max. image size', 'Input error', 'modal') ;
                    uicontrol(handles.edit_fillgap) ;
                    flagError = true ;
                    return ;
                end ;
            
                sValue = get(handles.edit_minlength, 'String') ;
                if isnan(str2double(sValue)) || str2double(sValue) <= 1
                    hError = errordlg('Discard length must be a number of pixels, > 1 and < max. image size', 'Input error', 'modal') ;
                    uicontrol(handles.edit_minlength) ;
                    flagError = true ;
                    return ;
                end 
            
                Iinfo = imfinfo(get(handles.edit_filename, 'String')) ;
                if Iinfo.BitDepth ~= 8 || ~strcmp('grayscale', Iinfo.ColorType)
                    disp('Image file format error - check BitDepth (8) and ColorType(''grayscale'')') ;
                    disp(Iinfo) ;
                    hError = errordlg({'Selected image file is not 8-bit & grayscale';'Check Command Window for file info, BitDepth and ColorType'}, ...
                                            'Input error', 'modal') ;
                    uicontrol(handles.edit_filename) ;
                    flagError = true ;
                    return ;
                end 
            
                handles.selmultifile = { } ;
            
            %   node file validation
            else
            
                sFilename = [ char(handles.selpath), char(handles.selfile) ] ;
            
                %   convert .svg file to .txt file
                if contains(sFilename, '.svg')
            
                    nConvert = convertSVG2txt_colour(sFilename) ;
            %         disp(nConvert) ;
            
                    if nConvert > 0
            
                        %   now handle possible multiple files
                        if nConvert > 1
            
                            sFilenameHead = sFilename(1:strfind(sFilename, '.svg')-1) ;
                            infoConvertedFiles = dir([sFilenameHead, '_colour*_converted.txt']) ;
                            handles.selmultifile = { infoConvertedFiles.name } ;
                            sColour = '' ;
                            for i = 1:nConvert
            
                                sThisFileName = char(handles.selmultifile(i)) ;
                                iHex = regexp(sThisFileName, '[A-F,0-9]{6}', 'ONCE') ;
            
            %                     disp(sThisFileName) ;
            %                     disp(iHex) ;
            
                                %   check each file has 'HHHHHH' in the name i.e. a valid hex string for colour
                                if isempty(iHex)
                                    hError = errordlg('For a colour file, all filenames must contain a 6 character hexadecimal colour code', 'Input error', 'modal') ;
                                    uicontrol(handles.edit_filename) ;
                                    flagError = true ;
                                    return ;
                                else
                                    iRGB = strfind(sThisFileName, 'colour') ;
                                    sRGB = sThisFileName(iRGB+6:iRGB+11) ;
                                    sColour = [ sColour ; hexRGB(sRGB) ]  ;
                                end ;
            
                            end ;
            
                            handles.cstrColours = cellstr(sColour) ;
            
                        else
            
                            handles.selmultifile = { } ;
                            handles.selfile = strrep(handles.selfile, '.svg', '_converted.txt') ;
            
                        end ;
            
                    else
            
                        hError = errordlg('Error converting .svg file to .txt; check .svg file is version 1.1', 'Input error', 'modal') ;
                        uicontrol(handles.edit_filename) ;
                        flagError = true ;
            
                    end ;
            
                else
            
                    handles.selmultifile = { } ;
            
                end ;
            
            end ;
            
            sValue = get(handles.edit_scaling, 'String') ;
            if ~isempty(sValue)
                if isnan(str2double(sValue)) || str2double(sValue) <= 0.0
                    hError = errordlg('Scaling can be blank (i.e. scale as is) or a positive number', 'Input error', 'modal') ;
                    uicontrol(handles.edit_scaling) ;
                    flagError = true ;
                    return ;
                end ;
            end ;
            
            if ~flagError
            
                sFilename = [ char(handles.selpath), char(handles.selfile) ] ;
                %   get the current RGB colour string
                sColour = char(handles.cstrColours(handles.iCurrentColour)) ;
            
                if isempty(get(handles.edit_scaling, 'String'))
                    nPixels = 0 ;
                else
                    nPixels = str2double(get(handles.edit_scaling, 'String')) ;
                end ;
            
                if get(handles.radiobutton_image, 'Value')
            
                    nHoughPeaks = str2double(get(handles.edit_houghpeaks, 'String')) ;
                    dHoughThreshold = str2double(get(handles.edit_houghthreshold, 'String')) ;
                    dfillgap = str2double(get(handles.edit_fillgap, 'String')) ;
                    dminlength = str2double(get(handles.edit_minlength, 'String')) ;
            
                    set(handles.text_message, 'String', 'Running Hough transform on image...') ;
                    [ handles.traces, handles.xmin, handles.ymin, handles.xmax, handles.ymax ] = GBPaQimage(sFilename, nPixels, ...
                                                                        nHoughPeaks, dHoughThreshold, dfillgap, dminlength, handles.axes_tracemap) ;
                else
            
                    set(handles.text_message, 'String', 'Reading node file...') ;
            
                    %   allow for multiple files, one for each colour
                    if ~isempty(handles.selmultifile)
            
                        handles.traces = struct([]) ;
                        handles.nTraces = 0 ;
                        handles.nSegments = 0 ;
                        handles.nNodes = 0 ;
                        handles.xmin = 3e38 ;
                        handles.ymin = 3e38 ;
                        handles.xmax = -3e38 ;
                        handles.ymax = -3e38 ;
                        hold on ;
                        for i = 1:length(handles.selmultifile)
            
                            sFilename = [ char(handles.selpath), char(handles.selmultifile(i)) ] ;
                            sColour = char(handles.cstrColours(i)) ;
            
                            [ traces, xmin, ymin, xmax, ymax ] = GBPaQlist(sFilename, nPixels, handles.axes_tracemap, sColour) ;
            
                            handles.traces = [ handles.traces, traces ] ;
            
                            if xmin < handles.xmin
                                handles.xmin = xmin ;
                            end ;
                            if ymin < handles.ymin
                                handles.ymin = ymin ;
                            end ;
                            if xmax > handles.xmax
                                handles.xmax = xmax ;
                            end ;
                            if ymax > handles.ymax
                                handles.ymax = ymax ;
                            end ;
            
                        end ;
                        hold off ;
            
                    else
            
                        hold on ;
                        [ handles.traces, handles.xmin, handles.ymin, handles.xmax, handles.ymax ] = GBPaQlist(sFilename, nPixels, handles.axes_tracemap, sColour) ;
                        hold off ;
            
                    end ;
            
                end ;
            
                set(handles.text_message, 'String', 'Collecting fracture trace data...') ;
            
                setappdata(handles.pushbutton_run, 'Traces', handles.traces) ;
                setappdata(handles.pushbutton_run, 'xMax', handles.xmax) ;
                setappdata(handles.pushbutton_run, 'yMax', handles.ymax) ;
                setappdata(handles.pushbutton_run, 'xMin', handles.xmin) ;
                setappdata(handles.pushbutton_run, 'yMin', handles.ymin) ;
            
                handles.nTraces = length(handles.traces) ;
                handles.nSegments = sum([handles.traces(:).nSegments]) ;
                handles.nNodes = sum([handles.traces(:).nNodes]) ;
            
                set(handles.label_stats, 'String', {['Min. X coordinate: ', num2str(handles.xmin)], ...
                                                    ['Min. Y coordinate: ', num2str(handles.ymin)], ...
                                                    ['Max. X coordinate: ', num2str(handles.xmax)], ...
                                                    ['Max. Y coordinate: ', num2str(handles.ymax)], ...
                                                    ['Number of traces: ', num2str(handles.nTraces)], ...
                                                    ['Number of segments: ', num2str(handles.nSegments)], ...
                                                    ['Number of nodes: ', num2str(handles.nNodes)]}) ;
            
                set(handles.text_message, 'String', 'Ready. Click Run to generate maps and graphs.') ;
            
                set(handles.pushbutton_run, 'Enable', 'on') ;
                set(handles.pbMapsTab, 'Enable', 'on') ;
                set(handles.pbLengthsTab, 'Enable', 'on') ;
                set(handles.pbAnglesTab, 'Enable', 'on') ;
                set(handles.pbInterceptsTab, 'Enable', 'on') ;
                set(handles.pushbutton_flipx, 'Enable', 'on') ;
                set(handles.pushbutton_flipy, 'Enable', 'on') ;
            %    uicontrol(handles.pushbutton_run) ;
                uicontrol(handles.pbMapsTab) ;
                set(handles.uibgTabMaps, 'Visible', 'on') ;
            
                % Update handles structure
                guidata(hObject, handles);
            
            end
        end

        % Button pushed function: pushbutton_run
        function pushbutton_run_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_run (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            flagError = false ;
            
            sValue = get(handles.edit_numscancircles, 'String') ;
            if isnan(str2double(sValue)) || str2double(sValue) < 2
                hError = errordlg('Number of scan circles must be a positive whole number (e.g. 10-20)', 'Input error', 'modal') ;
                uicontrol(handles.edit_numscancircles) ;
                flagError = true ;
                return ;
            end ;
            
            sValue = get(handles.edit_degfromnorth, 'String') ;
            if isnan(str2double(sValue)) || str2double(sValue) > 360.0 || str2double(sValue) < -360.0
                hError = errordlg('Degrees from North must be a number <= 360 (use negative for CCW)', 'Input error', 'modal') ;
                uicontrol(handles.edit_degfromnorth) ;
                flagError = true ;
                return ;
            end ;
            
            if ~flagError
            
                traces = getappdata(hObject, 'Traces') ;
                xmin = getappdata(hObject, 'xMin') ;
                ymin = getappdata(hObject, 'yMin') ;
                xmax = getappdata(hObject, 'xMax') ;
                ymax = getappdata(hObject, 'yMax') ;
            
                %   get the current RGB colour string
                if ~isempty(handles.selmultifile)
                    sColour = '[ 0 0 1 ]' ;
                else
                    sColour = char(handles.cstrColours(handles.iCurrentColour)) ;
                end ;
            
                if length(traces) > 0
            
                    %   get values from text boxes
                    if isempty(get(handles.edit_scaling, 'String'))
                        nPixels = 0 ;
                    else
                        nPixels = str2double(get(handles.edit_scaling, 'String')) ;
                    end ;
            
                    if strcmp(handles.axes_tracemap.YDir, 'reverse')
                        flag_reverseY = true ;
                    else
                        flag_reverseY = false ;
                    end ;
            
                    if strcmp(handles.axes_tracemap.XDir, 'reverse')
                        flag_reverseX = true ;
                    else
                        flag_reverseX = false ;
                    end ;
            
                    nNorth = str2double(get(handles.edit_degfromnorth, 'String')) ;
                    nMLE_lc = str2double(get(handles.edit_lc, 'String')) ;
                    nMLE_uc = str2double(get(handles.edit_uc, 'String')) ;
            
                    %   get values from drop down lists
                    list = get(handles.popupmenu_histolengthbins, 'String') ;
                    nHistoLengthBins = str2double(list{get(handles.popupmenu_histolengthbins, 'Value')}) ;
            
                    list = get(handles.popupmenu_histoanglebins, 'String') ;
                    nHistoAngleBins = str2double(list{get(handles.popupmenu_histoanglebins, 'Value')}) ;
            
                    list = get(handles.popupmenu_rosebins, 'String') ;
                    nRoseBins = str2double(list{get(handles.popupmenu_rosebins, 'Value')}) ;
            
                    list = get(handles.popupmenu_segscanlineint, 'String') ;
                    nintinc = str2double(list{get(handles.popupmenu_segscanlineint, 'Value')}) ;

                    list = get(handles.popupmenu_seganglebin, 'String') ;
                    nintbin = str2double(list{get(handles.popupmenu_seganglebin, 'Value')}) ;
                    
                    %   call the various maps & graphs
                    flag_tracemap = get(handles.checkbox_tracemap, 'Value') ;
                    flag_shownodes = get(handles.checkbox_shownodes, 'Value') ;
                    flag_tracesbylength = get(handles.checkbox_tracemaplength, 'Value') ;
                    flag_segmentsbylength = get(handles.checkbox_segmentmaplength, 'Value') ;
                    flag_segmentsbystrike = get(handles.checkbox_segmentmapstrike, 'Value') ;
            
                    flag_tracemap_any = false ;
                    if sum([flag_tracemap, flag_tracesbylength, ...
                            flag_segmentsbylength, flag_segmentsbystrike]) > 0
                        flag_tracemap_any = true ;
                    end ;
            
                    flag_intensitymap = get(handles.checkbox_intensitymap, 'Value') ;
                    flag_densitymap = get(handles.checkbox_densitymap, 'Value') ;
                    flag_showcircles = get(handles.checkbox_showcircles, 'Value') ;
                    nCircles = round(str2double(get(handles.edit_numscancircles, 'String'))) ;
            
                    flag_histolength = get(handles.checkbox_histolength, 'Value') ;
                    flag_logloglength = get(handles.checkbox_logloglength, 'Value') ;
                    flag_crossplot = get(handles.checkbox_crossplot, 'Value') ;
                    flag_censor = get(handles.checkbox_censor, 'Value') ;
                    flag_mle = get(handles.checkbox_mle, 'Value') ;
            
                    flag_histoangle = get(handles.checkbox_histoangle, 'Value') ;
                    flag_roseangle = get(handles.checkbox_rose, 'Value') ;
                    flag_rosemean = get(handles.checkbox_showrosemean, 'Value') ;
                    flag_roselengthweighted = get(handles.checkbox_roselengthweighted, 'Value') ;
                    flag_rosecolour = get(handles.checkbox_rosecolour, 'Value') ;
            
                    flag_cracktensor = get(handles.checkbox_cracktensor, 'Value') ;
            
                    flag_segintden = get(handles.checkbox_segintercept, 'Value') ;
                    flag_segintdennorm = get(handles.checkbox_seginterceptnorm, 'Value') ; 
                    flag_segangintmax = get(handles.checkbox_seganglemax, 'Value') ;
                    flag_segangintmin = get(handles.checkbox_seganglemin, 'Value') ;
                    flag_showmin = get(handles.checkbox_segintmin, 'Value') ;
                    flag_showmax = get(handles.checkbox_segintmax, 'Value') ;
                    flag_showmean = get(handles.checkbox_segintmean, 'Value') ;
                    
                    if length(handles.cstrColours) > 1
                        flag_multicolour = 1 ;
                    else
                        flag_multicolour = 0 ;
                    end ;
            
                    if flag_tracemap_any
                        GBPaQtracemap(traces, nPixels, nNorth, ...
                                xmin, ymin, xmax, ymax, ...
                                flag_shownodes, flag_reverseY, flag_reverseX, sColour, ...
                                flag_multicolour, flag_tracemap, ...
                                flag_tracesbylength, flag_segmentsbylength, flag_segmentsbystrike) ;
                    end ;
            
                    flag_length = sum([flag_histolength, flag_logloglength]) ;
                    if flag_length
                        GBPaQlength(traces, nPixels, nNorth, xmin, ymin, xmax, ymax, ...
                                           nHistoLengthBins, flag_histolength, flag_logloglength, ...
                                           flag_crossplot, flag_mle, flag_censor, ...
                                           flag_reverseY, flag_reverseX, ...
                                           sColour, nMLE_lc, nMLE_uc) ;
                    end ;
            
                    flag_angle = sum([flag_histoangle, flag_roseangle, flag_cracktensor]) ;
                    if flag_angle
                        GBPaQangle(traces, nNorth, xmin, ymin, xmax, ymax, nHistoAngleBins, nRoseBins, ...
                                                flag_histoangle, flag_roseangle, ...
                                                flag_reverseY, flag_reverseX, ...
                                                flag_cracktensor, ...
                                                flag_roselengthweighted, flag_rosemean, ...
                                                flag_rosecolour, sColour) ;
                    end
            
                    flag_pattern = sum([flag_intensitymap, flag_densitymap]) ;
                    if flag_pattern
                        GBPaQpattern(traces, nPixels, xmin, ymin, xmax, ymax, ...
                                                flag_intensitymap, flag_densitymap, flag_showcircles, nCircles, flag_reverseY, flag_reverseX, sColour) ;
                    end
            
                    flag_intercepts = sum([flag_segintden, flag_segintdennorm, ... 
                                           flag_segangintmax, flag_segangintmin]) ;
                    if flag_intercepts 
                        GBPaQintercepts(traces, xmin, ymin, xmax, ymax, nintinc, nintbin, ... 
                            flag_segintden, flag_segintdennorm, ...
                            flag_segangintmax, flag_segangintmin, ...
                            flag_showmin, flag_showmax, flag_showmean)
                    end
                    
                    set(handles.text_message, 'String', 'All done. Check current folder for plot files.') ;
                    uicontrol(handles.pushbutton_run) ;
            
                else
            
                    errordlg('No traces found; check the input file is OK.') ;
                    uicontrol(handles.pushbutton_browse) ;
            
                end ;
            
            end ;
        end

        % Button pushed function: pbInterceptsTab
        function pbInterceptsTabButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbAnglesTab (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.uibgTabMaps, 'Visible', 'off') ;
            set(handles.uibgTabLengths, 'Visible', 'off') ;
            set(handles.uibgTabAngles, 'Visible', 'off') ;
            set(handles.uibgTabIntercepts, 'Visible', 'on') ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure_main = uifigure('Visible', 'off');
            app.figure_main.Name = 'GBPaQ';
            app.figure_main.Tag = 'figure_main';
            app.axes_icon = uiaxes(app.figure_main);
            app.axes_icon.Tag = 'axes_icon';
            app.axes_tracemap = uiaxes(app.figure_main);
            app.axes_tracemap.Tag = 'axes_tracemap';
            app.axes_logo = uiaxes(app.figure_main);
            app.axes_logo.Tag = 'axes_logo';
            app.text19 = uilabel(app.figure_main);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Grain Boundary Pattern Quantification';
            app.uipanel1 = uipanel(app.figure_main);
            app.uipanel1.Title = 'Input';
            app.uipanel1.Tag = 'uipanel1';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Filename';
            app.uibuttongroup1 = uibuttongroup(app.uipanel1);
            app.uibuttongroup1.Title = 'Input file type';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.radiobutton_image = uiradiobutton(app.uibuttongroup1);
            app.radiobutton_image.Tag = 'radiobutton_image';
            app.radiobutton_image.Text = 'Image file';
            app.radiobutton_node = uiradiobutton(app.uibuttongroup1);
            app.radiobutton_node.Tag = 'radiobutton_node';
            app.radiobutton_node.Text = 'Node file';
            app.radiobutton_node.Value = true;
            app.uibuttongroup5 = uibuttongroup(app.uipanel1);
            app.uibuttongroup5.Title = 'Image file/Hough transform options';
            app.uibuttongroup5.Tag = 'uibuttongroup5';
            app.label_houghpeaks = uilabel(app.uibuttongroup5);
            app.label_houghpeaks.Tag = 'label_houghpeaks';
            app.label_houghpeaks.Text = 'Number of Hough peaks';
            app.label_houghthreshold = uilabel(app.uibuttongroup5);
            app.label_houghthreshold.Tag = 'label_houghthreshold';
            app.label_houghthreshold.Text = 'Hough threshold';
            app.label_fillgap = uilabel(app.uibuttongroup5);
            app.label_fillgap.Tag = 'label_fillgap';
            app.label_fillgap.Text = 'Merge gaps less than';
            app.label_minlength = uilabel(app.uibuttongroup5);
            app.label_minlength.Tag = 'label_minlength';
            app.label_minlength.Text = 'Discard lengths less than';
            app.edit_houghpeaks = uieditfield(app.uibuttongroup5, 'text');
            app.edit_houghpeaks.Tag = 'edit_houghpeaks';
            app.edit_houghpeaks.Enable = 'off';
            app.edit_houghpeaks.Value = '1000';
            app.edit_houghthreshold = uieditfield(app.uibuttongroup5, 'text');
            app.edit_houghthreshold.Tag = 'edit_houghthreshold';
            app.edit_houghthreshold.Enable = 'off';
            app.edit_houghthreshold.Value = '0.33';
            app.edit_fillgap = uieditfield(app.uibuttongroup5, 'text');
            app.edit_fillgap.Tag = 'edit_fillgap';
            app.edit_fillgap.Enable = 'off';
            app.edit_fillgap.Value = '5';
            app.edit_minlength = uieditfield(app.uibuttongroup5, 'text');
            app.edit_minlength.Tag = 'edit_minlength';
            app.edit_minlength.Enable = 'off';
            app.edit_minlength.Value = '3';
            app.pushbutton_browse = uibutton(app.uipanel1, 'push');
            app.pushbutton_browse.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_browse_Callback, true);
            app.pushbutton_browse.Tag = 'pushbutton_browse';
            app.pushbutton_browse.Tooltip = 'Click here to browse for input files';
            app.pushbutton_browse.Text = 'Browse...';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Scaling (pixels/metre)';
            app.edit_scaling = uieditfield(app.uipanel1, 'text');
            app.edit_scaling.Tag = 'edit_scaling';
            app.pushbutton_preview = uibutton(app.uipanel1, 'push');
            app.pushbutton_preview.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_preview_Callback, true);
            app.pushbutton_preview.Tag = 'pushbutton_preview';
            app.pushbutton_preview.Enable = 'off';
            app.pushbutton_preview.Tooltip = 'Click here to view the selected input file';
            app.pushbutton_preview.Text = 'Preview';
            app.edit_filename = uieditfield(app.uipanel1, 'text');
            app.edit_filename.Tag = 'edit_filename';
            app.edit_filename.Enable = 'off';
            app.edit_filename.Value = '[no file selected]';
            app.pushbutton_flipy = uibutton(app.uipanel1, 'push');
            app.pushbutton_flipy.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_flipy_Callback, true);
            app.pushbutton_flipy.Tag = 'pushbutton_flipy';
            app.pushbutton_flipy.Enable = 'off';
            app.pushbutton_flipy.Text = 'Flip Y-axis';
            app.pushbutton_flipx = uibutton(app.uipanel1, 'push');
            app.pushbutton_flipx.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_flipx_Callback, true);
            app.pushbutton_flipx.Tag = 'pushbutton_flipx';
            app.pushbutton_flipx.Enable = 'off';
            app.pushbutton_flipx.Text = 'Flip X-axis';
            app.uibuttongroup7 = uibuttongroup(app.figure_main);
            app.uibuttongroup7.Title = 'Statistics for selected file';
            app.uibuttongroup7.Tag = 'uibuttongroup7';
            app.label_stats = uilabel(app.uibuttongroup7);
            app.label_stats.Tag = 'label_stats';
            app.label_stats.Text = '';
            app.text_message = uilabel(app.figure_main);
            app.text_message.Tag = 'text_message';
            app.text_message.Text = 'Ready.';
            app.uibgTabMaps = uibuttongroup(app.figure_main);
            app.uibgTabMaps.Title = 'Maps';
            app.uibgTabMaps.Tag = 'uibgTabMaps';
            app.checkbox_shownodes = uicheckbox(app.uibgTabMaps);
            app.checkbox_shownodes.Tag = 'checkbox_shownodes';
            app.checkbox_shownodes.Text = 'Show nodes';
            app.checkbox_tracemap = uicheckbox(app.uibgTabMaps);
            app.checkbox_tracemap.ValueChangedFcn = createCallbackFcn(app, @checkbox_tracemap_Callback, true);
            app.checkbox_tracemap.Tag = 'checkbox_tracemap';
            app.checkbox_tracemap.Text = 'Traces, segments';
            app.checkbox_tracemap.Value = true;
            app.uipanel6 = uipanel(app.uibgTabMaps);
            app.uipanel6.Title = 'Colour-coded maps';
            app.uipanel6.Tag = 'uipanel6';
            app.checkbox_tracemaplength = uicheckbox(app.uipanel6);
            app.checkbox_tracemaplength.Tag = 'checkbox_tracemaplength';
            app.checkbox_tracemaplength.Text = 'Traces, by length';
            app.checkbox_segmentmaplength = uicheckbox(app.uipanel6);
            app.checkbox_segmentmaplength.Tag = 'checkbox_segmentmaplength';
            app.checkbox_segmentmaplength.Text = 'Segments, by length';
            app.checkbox_segmentmapstrike = uicheckbox(app.uipanel6);
            app.checkbox_segmentmapstrike.Tag = 'checkbox_segmentmapstrike';
            app.checkbox_segmentmapstrike.Text = 'Segments, by strike';
            app.checkbox_segmentmapstrike.Value = true;
            app.uipanel4 = uipanel(app.uibgTabMaps);
            app.uipanel4.Title = 'Intensity & Density';
            app.uipanel4.Tag = 'uipanel4';
            app.checkbox_intensitymap = uicheckbox(app.uipanel4);
            app.checkbox_intensitymap.ValueChangedFcn = createCallbackFcn(app, @checkbox_intensitymap_Callback, true);
            app.checkbox_intensitymap.Tag = 'checkbox_intensitymap';
            app.checkbox_intensitymap.Text = 'Estimated Intensity, P21';
            app.checkbox_densitymap = uicheckbox(app.uipanel4);
            app.checkbox_densitymap.ValueChangedFcn = createCallbackFcn(app, @checkbox_densitymap_Callback, true);
            app.checkbox_densitymap.Tag = 'checkbox_densitymap';
            app.checkbox_densitymap.Text = 'Estimated Density, P20';
            app.checkbox_showcircles = uicheckbox(app.uipanel4);
            app.checkbox_showcircles.Tag = 'checkbox_showcircles';
            app.checkbox_showcircles.Enable = 'off';
            app.checkbox_showcircles.Text = 'Show scan circles';
            app.label_numscancircles = uilabel(app.uipanel4);
            app.label_numscancircles.Tag = 'label_numscancircles';
            app.label_numscancircles.Enable = 'off';
            app.label_numscancircles.Text = 'Number of scan circles';
            app.edit_numscancircles = uieditfield(app.uipanel4, 'text');
            app.edit_numscancircles.Tag = 'edit_numscancircles';
            app.edit_numscancircles.Enable = 'off';
            app.edit_numscancircles.Value = '12';
            app.uibgTabButtons = uibuttongroup(app.figure_main);
            app.uibgTabButtons.Tag = 'uibgTabButtons';
            app.pbMapsTab = uibutton(app.uibgTabButtons, 'push');
            app.pbMapsTab.ButtonPushedFcn = createCallbackFcn(app, @pbMapsTab_Callback, true);
            app.pbMapsTab.Tag = 'pbMapsTab';
            app.pbMapsTab.Enable = 'off';
            app.pbMapsTab.Text = 'Maps';
            app.pbAnglesTab = uibutton(app.uibgTabButtons, 'push');
            app.pbAnglesTab.ButtonPushedFcn = createCallbackFcn(app, @pbAnglesTab_Callback, true);
            app.pbAnglesTab.Tag = 'pbAnglesTab';
            app.pbAnglesTab.Enable = 'off';
            app.pbAnglesTab.Text = 'Angles';
            app.pbLengthsTab = uibutton(app.uibgTabButtons, 'push');
            app.pbLengthsTab.ButtonPushedFcn = createCallbackFcn(app, @pbLengthsTab_Callback, true);
            app.pbLengthsTab.Tag = 'pbLengthsTab';
            app.pbLengthsTab.Enable = 'off';
            app.pbLengthsTab.Text = 'Lengths';
            app.pbInterceptsTab = uibutton(app.uibgTabButtons, 'push');
            app.pbInterceptsTab.ButtonPushedFcn = createCallbackFcn(app, @pbInterceptsTabButtonPushed, true);
            app.pbInterceptsTab.Tag = 'pbInterceptsTab';
            app.pbInterceptsTab.Enable = 'off';
            app.pbInterceptsTab.Text = 'Intercepts';
            app.pushbutton_run = uibutton(app.figure_main, 'push');
            app.pushbutton_run.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_run_Callback, true);
            app.pushbutton_run.Tag = 'pushbutton_run';
            app.pushbutton_run.Enable = 'off';
            app.pushbutton_run.Tooltip = 'Click here to run the selected options';
            app.pushbutton_run.Text = 'Run';
            app.pushbutton_exit = uibutton(app.figure_main, 'push');
            app.pushbutton_exit.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_exit_Callback, true);
            app.pushbutton_exit.Tag = 'pushbutton_exit';
            app.pushbutton_exit.Tooltip = 'Click here to end the program';
            app.pushbutton_exit.Text = 'Exit';
            app.text4 = uilabel(app.figure_main);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Version 1.0, October 2021';
            app.uibgTabLengths = uibuttongroup(app.figure_main);
            app.uibgTabLengths.Title = 'Lengths & sizes';
            app.uibgTabLengths.Tag = 'uibgTabLengths';
            app.edit_uc = uieditfield(app.uibgTabLengths, 'text');
            app.edit_uc.Tag = 'edit_uc';
            app.edit_uc.Enable = 'off';
            app.edit_uc.Value = '0';
            app.label_uc = uilabel(app.uibgTabLengths);
            app.label_uc.Tag = 'label_uc';
            app.label_uc.Enable = 'off';
            app.label_uc.Text = 'Upper cut-off, %';
            app.edit_lc = uieditfield(app.uibgTabLengths, 'text');
            app.edit_lc.Tag = 'edit_lc';
            app.edit_lc.Enable = 'off';
            app.edit_lc.Value = '0';
            app.popupmenu_histolengthbins = uidropdown(app.uibgTabLengths);
            app.popupmenu_histolengthbins.Items = {'10', '20', '30', '50'};
            app.popupmenu_histolengthbins.Tag = 'popupmenu_histolengthbins';
            app.popupmenu_histolengthbins.Value = '10';
            app.text_histolengthbins = uilabel(app.uibgTabLengths);
            app.text_histolengthbins.Tag = 'text_histolengthbins';
            app.text_histolengthbins.Text = 'Number of bins';
            app.checkbox_crossplot = uicheckbox(app.uibgTabLengths);
            app.checkbox_crossplot.Tag = 'checkbox_crossplot';
            app.checkbox_crossplot.Text = 'Cross-plot lengths v angles ';
            app.checkbox_mle = uicheckbox(app.uibgTabLengths);
            app.checkbox_mle.ValueChangedFcn = createCallbackFcn(app, @checkbox_mle_Callback, true);
            app.checkbox_mle.Tag = 'checkbox_mle';
            app.checkbox_mle.Enable = 'off';
            app.checkbox_mle.Text = 'MLE analysis';
            app.checkbox_censor = uicheckbox(app.uibgTabLengths);
            app.checkbox_censor.Tag = 'checkbox_censor';
            app.checkbox_censor.Text = 'Censor lengths at map edges';
            app.checkbox_logloglength = uicheckbox(app.uibgTabLengths);
            app.checkbox_logloglength.ValueChangedFcn = createCallbackFcn(app, @checkbox_logloglength_Callback, true);
            app.checkbox_logloglength.Tag = 'checkbox_logloglength';
            app.checkbox_logloglength.Text = 'Log-log lengths';
            app.checkbox_histolength = uicheckbox(app.uibgTabLengths);
            app.checkbox_histolength.ValueChangedFcn = createCallbackFcn(app, @checkbox_histolength_Callback, true);
            app.checkbox_histolength.Tag = 'checkbox_histolength';
            app.checkbox_histolength.Text = 'Histogram of lengths';
            app.checkbox_histolength.Value = true;
            app.label_lc = uilabel(app.uibgTabLengths);
            app.label_lc.Tag = 'label_lc';
            app.label_lc.Enable = 'off';
            app.label_lc.Text = 'Lower cut-off, %';
            app.uibuttongroup12 = uibuttongroup(app.uibgTabLengths);
            app.uibuttongroup12.Title = 'Grain boundary tensor';
            app.uibuttongroup12.Tag = 'uibuttongroup12';
            app.checkbox_cracktensor = uicheckbox(app.uibuttongroup12);
            app.checkbox_cracktensor.Tag = 'checkbox_cracktensor';
            app.checkbox_cracktensor.Text = 'Grain boundary tensor plot';
            app.uibgTabAngles = uibuttongroup(app.figure_main);
            app.uibgTabAngles.Title = 'Angles & orientation distributions';
            app.uibgTabAngles.Tag = 'uibgTabAngles';
            app.checkbox_rosecolour = uicheckbox(app.uibgTabAngles);
            app.checkbox_rosecolour.Tag = 'checkbox_rosecolour';
            app.checkbox_rosecolour.Text = 'Colour by strike?';
            app.checkbox_rosecolour.Value = true;
            app.checkbox_showrosemean = uicheckbox(app.uibgTabAngles);
            app.checkbox_showrosemean.Tag = 'checkbox_showrosemean';
            app.checkbox_showrosemean.Text = 'Show mean vector?';
            app.checkbox_roselengthweighted = uicheckbox(app.uibgTabAngles);
            app.checkbox_roselengthweighted.Tag = 'checkbox_roselengthweighted';
            app.checkbox_roselengthweighted.Text = 'Length weighted?';
            app.checkbox_histoangle = uicheckbox(app.uibgTabAngles);
            app.checkbox_histoangle.ValueChangedFcn = createCallbackFcn(app, @checkbox_histoangle_Callback, true);
            app.checkbox_histoangle.Tag = 'checkbox_histoangle';
            app.checkbox_histoangle.Text = 'Histogram of angles';
            app.checkbox_rose = uicheckbox(app.uibgTabAngles);
            app.checkbox_rose.ValueChangedFcn = createCallbackFcn(app, @checkbox_rose_Callback, true);
            app.checkbox_rose.Tag = 'checkbox_rose';
            app.checkbox_rose.Text = 'Rose diagram (equal area)';
            app.checkbox_rose.Value = true;
            app.edit_degfromnorth = uieditfield(app.uibgTabAngles, 'text');
            app.edit_degfromnorth.Tag = 'edit_degfromnorth';
            app.edit_degfromnorth.Value = '0';
            app.label_degfromnorth = uilabel(app.uibgTabAngles);
            app.label_degfromnorth.Tag = 'label_degfromnorth';
            app.label_degfromnorth.Text = 'Rotate Y-axis from N (°)';
            app.text_histoanglebins = uilabel(app.uibgTabAngles);
            app.text_histoanglebins.Tag = 'text_histoanglebins';
            app.text_histoanglebins.Enable = 'off';
            app.text_histoanglebins.Text = 'Number of bins';
            app.popupmenu_histoanglebins = uidropdown(app.uibgTabAngles);
            app.popupmenu_histoanglebins.Items = {'10', '20', '30', '50'};
            app.popupmenu_histoanglebins.Tag = 'popupmenu_histoanglebins';
            app.popupmenu_histoanglebins.Enable = 'off';
            app.popupmenu_histoanglebins.Value = '10';
            app.text_rosebins = uilabel(app.uibgTabAngles);
            app.text_rosebins.Tag = 'text_rosebins';
            app.text_rosebins.Text = 'Bin size (°)';
            app.popupmenu_rosebins = uidropdown(app.uibgTabAngles);
            app.popupmenu_rosebins.Items = {'5', '10', '20', '30'};
            app.popupmenu_rosebins.Tag = 'popupmenu_rosebins';
            app.popupmenu_rosebins.Value = '5';
            app.uibgTabIntercepts = uibuttongroup(app.figure_main);
            app.uibgTabIntercepts.Title = 'Intercepts from radial scan lines';
            app.uibgTabIntercepts.Tag = 'uibgTabIntercepts';
            app.checkbox_seganglemin = uicheckbox(app.uibgTabIntercepts);
            app.checkbox_seganglemin.Tag = 'checkbox_seganglemin';
            app.checkbox_seganglemin.Text = 'Segment angles intercepted along min';
            app.checkbox_seganglemin.Value = true;
            app.checkbox_seganglemax = uicheckbox(app.uibgTabIntercepts);
            app.checkbox_seganglemax.Tag = 'checkbox_seganglemax';
            app.checkbox_seganglemax.Text = 'Segment angles intercepted along max';
            app.checkbox_seganglemax.Value = true;
            app.checkbox_segintercept = uicheckbox(app.uibgTabIntercepts);
            app.checkbox_segintercept.Tag = 'checkbox_segintercept';
            app.checkbox_segintercept.Text = 'Segment intercept density';
            app.checkbox_segintercept.Value = true;
            app.checkbox_seginterceptnorm = uicheckbox(app.uibgTabIntercepts);
            app.checkbox_seginterceptnorm.Tag = 'checkbox_seginterceptnorm';
            app.checkbox_seginterceptnorm.Text = 'Normalised segment intercept density';
            app.checkbox_seginterceptnorm.Value = true;
            app.text_segscanlineint = uilabel(app.uibgTabIntercepts);
            app.text_segscanlineint.Tag = 'text_segscanlineint';
            app.text_segscanlineint.Text = 'Scan line interval (°)';
            app.popupmenu_segscanlineint = uidropdown(app.uibgTabIntercepts);
            app.popupmenu_segscanlineint.Items = {'1', '2', '5', '10', '20', '30'};
            app.popupmenu_segscanlineint.Tag = 'popupmenu_segscanlineint';
            app.popupmenu_segscanlineint.Value = '5';
            app.text_seganglebin = uilabel(app.uibgTabIntercepts);
            app.text_seganglebin.Tag = 'text_seganglebin';
            app.text_seganglebin.Text = 'Bin size (°)';
            app.popupmenu_seganglebin = uidropdown(app.uibgTabIntercepts);
            app.popupmenu_seganglebin.Items = {'5', '10', '20', '30'};
            app.popupmenu_seganglebin.Tag = 'popupmenu_seganglebin';
            app.popupmenu_seganglebin.Value = '10';
            app.RosediagramsLabel = uilabel(app.uibgTabIntercepts);
            app.RosediagramsLabel.Text = 'Rose diagrams';
            app.PolarplotsLabel = uilabel(app.uibgTabIntercepts);
            app.PolarplotsLabel.Text = 'Polar plots';
            app.checkbox_segintmin = uicheckbox(app.uibgTabIntercepts);
            app.checkbox_segintmin.Tag = 'checkbox_segintmin';
            app.checkbox_segintmin.Text = 'Show minimum density direction';
            app.checkbox_segintmin.Value = true;
            app.checkbox_segintmax = uicheckbox(app.uibgTabIntercepts);
            app.checkbox_segintmax.Tag = 'checkbox_segintmax';
            app.checkbox_segintmax.Text = 'Show maximum density direction';
            app.checkbox_segintmax.Value = true;
            app.checkbox_segintmean = uicheckbox(app.uibgTabIntercepts);
            app.checkbox_segintmean.Tag = 'checkbox_segintmean';
            app.checkbox_segintmean.Text = 'Show mean density';
            app.checkbox_segintmean.Value = true;
            % Show the figure after all components are created
            app.figure_main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GBPaQ_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure_main)

            % Execute the startup function
            runStartupFcn(app, @(app)GBPaQ_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_main)
        end
    end
end